classdef TP5_IAIM_CUCEI_2022A_EGAM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        tp5                       matlab.ui.Figure
        ImageMedicLabel           matlab.ui.control.Label
        AnonimizardatosButton     matlab.ui.control.Button
        HSVSwitch                 matlab.ui.control.Switch
        HSVSwitchLabel            matlab.ui.control.Label
        HotSwitch                 matlab.ui.control.Switch
        HotSwitchLabel            matlab.ui.control.Label
        Image_6                   matlab.ui.control.Image
        Image_5                   matlab.ui.control.Image
        Image_1                   matlab.ui.control.Image
        Image_4                   matlab.ui.control.Image
        Image_3                   matlab.ui.control.Image
        Image_2                   matlab.ui.control.Image
        ImportarimgenesButton     matlab.ui.control.Button
        EspectroLabel             matlab.ui.control.Label
        ValormximoEditField       matlab.ui.control.NumericEditField
        ValormximoEditFieldLabel  matlab.ui.control.Label
        ValormnimoEditField       matlab.ui.control.NumericEditField
        ValormnimoEditFieldLabel  matlab.ui.control.Label
        corte_axial               matlab.ui.control.NumericEditField
        NmerodecorteLabel_3       matlab.ui.control.Label
        corte_sagital             matlab.ui.control.NumericEditField
        NmerodecorteLabel_2       matlab.ui.control.Label
        slide_sagital             matlab.ui.control.Slider
        CortesLabel               matlab.ui.control.Label
        slide_coronal             matlab.ui.control.Slider
        CorteLabel                matlab.ui.control.Label
        corte_coronal             matlab.ui.control.NumericEditField
        NmerodecorteLabel         matlab.ui.control.Label
        slide_axial               matlab.ui.control.Slider
        CorteLabel_2              matlab.ui.control.Label
        sagital                   matlab.ui.control.UIAxes
        coronal                   matlab.ui.control.UIAxes
        axial                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        B;
        slice;
        slice_cor;
        slice_sag;
        slice_axi;
        min_intensity;
        max_intensity;
        global_max;
        global_min;
        rows;
        columns;
        num_img;
        value_map;
        value_hsv;
        DCMinfo;
        path;
        names;
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.corte_axial.Value = 1;
            app.corte_sagital.Value = 1;
            app.corte_coronal.Value = 1;
            app.ValormnimoEditField.Value = 0;
            app.ValormximoEditField.Value = 0;
            app.max_intensity = 0;
            app.min_intensity = 0;
            app.slide_axial.Visible='off';
            app.slide_coronal.Visible='off';
            app.slide_sagital.Visible='off';
            app.axial.Visible='off';
            app.sagital.Visible='off';
            app.coronal.Visible='off';
            app.Image_1.Visible='off';
            app.Image_5.Visible='off';
            app.Image_6.Visible='off';
            app.corte_axial.Editable='off';
            app.corte_sagital.Editable='off';
            app.corte_coronal.Editable='off';
            app.ValormnimoEditField.Editable='off';
            app.ValormximoEditField.Editable='off';
            app.HotSwitch.Enable='off';
            app.HSVSwitch.Enable='off';
            app.HSVSwitch.Value='Off';
            app.value_hsv = 'Off';
            app.value_map = 'Off';
            app.HotSwitch.Value='Off';
            app.axial.reset;
            app.sagital.reset;
            app.coronal.reset;
            app.axial.Visible='off';
            app.sagital.Visible='off';
            app.coronal.Visible='off';
            %clear all
        end

        % Button pushed function: ImportarimgenesButton
        function ImportarimgenesButtonPushed(app, event)
            startupFcn(app);
            app.Image_2.Visible='off';
            app.Image_3.Visible='off';
            app.Image_4.Visible='off';
            app.Image_1.Visible='on';
            app.Image_5.Visible='on';
            app.Image_6.Visible='on';
            app.path = strcat(uigetdir,'\');
            list_files = dir(app.path); %Listado de las imágenes DICOM en la carpeta
            app.names = {list_files.name}; %Nombres de las imágenes DICOM
            app.names = app.names(~strncmp(app.names,'.',1)); %Filtra para mantener solo imágenes DICOM y eliminar directorios
            [~, app.num_img] = size(app.names); %Obtiene la cantidad de imágenes DICOM
            %%Creación de matriz de ceros con dimensiones de una imagen DICOM.
            path_image = strcat(app.path,char(app.names(1))); %Ruta de la Imagen DICOM que sirve de base
            I_base = dicomread(path_image); %Lee la imagen DICOM base
            [app.rows, app.columns] = size(I_base); %Obtiene las filas y columnas de la imagen DICOM base
            image = zeros(app.rows,app.columns,app.num_img); %Crea una matriz de ceros con las dimensiones de la imagen DICOM base
            %%Inicialización de las variables relacionadas con el espectro de la imagen
            app.global_max = realmin; %Inicializa la variable que contendra el valor máximo del espectro
            app.global_min = realmax; %Inicializa la variable que contendra el valor mínimo del espectro
            %%Ciclo de acomodo y lectura de imágenes DICOM
            for i=1:1:app.num_img
                app.DCMinfo = dicominfo(strcat(app.path,string(app.names(i)))); %Obtiene la metadata de la imagen
                instancia = app.DCMinfo.InstanceNumber; %Obtiene el número de instancia para saber el orden
                h=char(app.names(i)); %Convierte a caracteres el nombre de la imagen
                h=strcat(app.path,h); %Concantena la ruta de la carpeta y el nombre de la imagen
                current_data = dicomread(h); %Lee la imagen de la ruta
                %Ecuación para ajuste de escala Hounsfield
                m = app.DCMinfo.RescaleSlope; %Obtiene el valor RescaleSlope de la metadata
                b = app.DCMinfo.RescaleIntercept; %Obtiene el valor RescaleIntercept de la metadata
                current_data_corregido = m*current_data+b; %Realiza la correción de la escala del espectro para la imagen
                current_max = max(max(current_data_corregido)); %Obtenemos el valor máximo del espectro para la imagen
                current_min = min(min(current_data_corregido)); %Obtenemos el valor mínimo del espectro para la imagen
                image(:,:,instancia)= current_data_corregido; %Se almacena la imagen en el número de instancia que le corresponde
                %Se define el minimo global del espectro de intensidades de la imagen
                if current_min < app.global_min
                    app.global_min = current_min; %Asigna el valor mínimo a global_min en caso de que el valor actual sea menor
                end
                %Se define el máximo global del espectro de intensidades de la imagen
                if(current_max > app.global_max)
                    app.global_max = current_max; %Asigna el valor máximo a global_max en caso de que el valor actual sea mayor
                end
            end
            %%Ajuste de imagen por medio de spacing
            app.B = image;
            %app.B= imresize3(image,[app.rows app.columns round(app.num_img*app.DCMinfo.SliceThickness/app.DCMinfo.PixelSpacing(1))]); %Redimensionamiento de la imagen utilizando el spacing
            app.slice_sag = permute(app.B(:,1,:),[3 1 2]); %Corte sagital
            app.slide_sagital.Limits=[1, app.rows];
            app.slice_cor = permute(app.B(1,:,:),[3,2,1]); %Corte coronal
            app.slide_coronal.Limits=[1,app.columns];
            app.slice_axi = permute(app.B(:,:,1),[1,2,3]); %Corte axial
            %app.num_img = round(app.num_img*app.DCMinfo.SliceThickness/app.DCMinfo.PixelSpacing(1));
            app.slide_axial.Limits=[1,app.num_img];
%             app.Image_1.Visible='off';
%             app.Image_5.Visible='off';
%             app.Image_6.Visible='off';
            app.slide_axial.Visible='on';
            app.slide_axial.Value = 1;
            app.slide_coronal.Visible='on';
            app.slide_coronal.Value = 1;
            app.slide_sagital.Visible='on';
            app.slide_sagital.Value = 1;
            pause(2)
            app.Image_1.Visible='off';
            app.Image_5.Visible='off';
            app.Image_6.Visible='off';
            app.corte_axial.Editable='on';
            app.corte_sagital.Editable='on';
            app.corte_coronal.Editable='on';
            app.ValormnimoEditField.Editable='on';
            app.ValormximoEditField.Editable='on';
            app.HotSwitch.Enable='on';
            app.HSVSwitch.Enable='on';
            app.axial.Visible='on';
            app.sagital.Visible='on';
            app.coronal.Visible='on';
            imshow(app.slice_cor(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.coronal);
            imshow(app.slice_axi(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.axial);             
            imshow(app.slice_sag(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.sagital);

        end

        % Value changed function: corte_axial
        function corte_axialValueChanged(app, event)
            value_field = app.corte_axial.Value;
            if value_field > app.num_img
                value_field = app.num_img;
            elseif value_field < 1
                value_field = 1;
            end
            app.corte_axial.Value = value_field;
            app.slide_axial.Value = value_field;
            app.slice_axi = permute(app.B(:,:,value_field),[1,2,3]); %Corte axial
            if strcmp(app.value_map,'On')
                imshow(app.slice_axi(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.axial,'Colormap',hot);
            elseif strcmp(app.value_hsv,'On')
                imshow(app.slice_axi(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.axial,'Colormap',hsv);
            else
                imshow(app.slice_axi(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.axial);
            end
        end

        % Value changed function: slide_axial
        function slide_axialValueChanged(app, event)
            value_slide = app.slide_axial.Value;
            value_slide = round(value_slide);
            app.corte_axial.Value = value_slide;
            app.slice_axi = permute(app.B(:,:,value_slide),[1,2,3]); %Corte axial
            if strcmp(app.value_map,'On')
                imshow(app.slice_axi(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.axial,'Colormap',hot);
            elseif strcmp(app.value_hsv,'On')
                imshow(app.slice_axi(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.axial,'Colormap',hsv);
            else
                imshow(app.slice_axi(:,:,1),[app.min_intensity, app.max_intensity],'Parent',app.axial);
            end
        end

        % Value changed function: ValormnimoEditField
        function ValormnimoEditFieldValueChanged(app, event)
            app.min_intensity = app.ValormnimoEditField.Value;
            if app.min_intensity > app.max_intensity
                app.min_intensity = app.max_intensity-1;
            end
            if app.min_intensity < app.global_min
                app.min_intensity = app.global_min;
            end
            app.ValormnimoEditField.Value = double(app.min_intensity);
            if strcmp(app.value_map,'On')
                imshow(app.slice_axi(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.axial,'Colormap',hot);
                imshow(app.slice_cor(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.coronal,'Colormap',hot);
                imshow(app.slice_sag(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.sagital,'Colormap',hot);
            elseif strcmp(app.value_hsv,'On')
                imshow(app.slice_axi(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.axial,'Colormap',hsv);
                imshow(app.slice_cor(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.coronal,'Colormap',hsv);
                imshow(app.slice_sag(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.sagital,'Colormap',hsv);
            else 
                imshow(app.slice_cor(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.coronal);
                imshow(app.slice_axi(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.axial);             
                imshow(app.slice_sag(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.sagital);
            end
        end

        % Value changed function: ValormximoEditField
        function ValormximoEditFieldValueChanged(app, event)
            app.max_intensity = app.ValormximoEditField.Value;
            if app.max_intensity < app.min_intensity
                app.max_intensity = app.min_intensity+1;
            end
            if app.max_intensity > app.global_max
                app.max_intensity = app.global_max;
            end
            app.ValormximoEditField.Value = double(app.max_intensity);
            if strcmp(app.value_map,'On')
                imshow(app.slice_axi(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.axial,'Colormap',hot);
                imshow(app.slice_cor(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.coronal,'Colormap',hot);
                imshow(app.slice_sag(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.sagital,'Colormap',hot);
            elseif strcmp(app.value_hsv,'On')
                imshow(app.slice_axi(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.axial,'Colormap',hsv);
                imshow(app.slice_cor(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.coronal,'Colormap',hsv);
                imshow(app.slice_sag(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.sagital,'Colormap',hsv);
            else    
                imshow(app.slice_axi(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.axial);
                imshow(app.slice_cor(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.coronal);
                imshow(app.slice_sag(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.sagital)

            end
        end

        % Value changed function: slide_coronal
        function slide_coronalValueChanged(app, event)
            value_slide = app.slide_coronal.Value;
            value_slide = round(value_slide);
            app.corte_coronal.Value = value_slide;
            app.slice_cor = permute(app.B(value_slide,:,:),[3,2,1]); %Corte coronal
            if strcmp(app.value_map,'On')
                imshow(app.slice_cor(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.coronal,'Colormap',hot);
            elseif strcmp(app.value_hsv,'On')
                imshow(app.slice_cor(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.coronal,'Colormap',hsv);
            else
                imshow(app.slice_cor(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.coronal);

            end
        end

        % Value changed function: corte_coronal
        function corte_coronalValueChanged(app, event)
            value_field = app.corte_coronal.Value;
            if value_field > app.columns
                value_field = app.columns;
            elseif value_field < 1
                value_field = 1;
            end
            app.slide_coronal.Value = value_field;
            app.corte_coronal.Value = value_field;
            app.slice_cor = permute(app.B(value_field,:,:),[3,2,1]); %Corte coronal
            if strcmp(app.value_map,'On')
                imshow(app.slice_cor(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.coronal,'Colormap',hot);
            elseif strcmp(app.value_hsv,'On')
                imshow(app.slice_cor(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.coronal,'Colormap',hsv);
            else
                imshow(app.slice_cor(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.coronal);
            end
        end

        % Value changed function: slide_sagital
        function slide_sagitalValueChanged(app, event)
            value_slide = app.slide_sagital.Value;
            value_slide = round(value_slide);
            app.corte_sagital.Value = value_slide;
            app.slice_sag = permute(app.B(:,value_slide,:),[3 1 2]); %Corte sagital
            if strcmp(app.value_map,'On')
                imshow(app.slice_sag(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.sagital,'Colormap',hot);
            elseif strcmp(app.value_hsv,'On')
                imshow(app.slice_sag(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.sagital,'Colormap',hsv);
            else
                imshow(app.slice_sag(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.sagital);
            end
        end

        % Value changed function: corte_sagital
        function corte_sagitalValueChanged(app, event)
            value_field = app.corte_sagital.Value;
            if value_field > app.rows
                value_field = app.rows;
            elseif value_field < 1
                value_field = 1;
            end
            app.corte_sagital.Value = value_field;
            app.slide_sagital.Value = value_field;
            app.slice_sag = permute(app.B(:,value_field,:),[3 1 2]); %Corte sagital
            if strcmp(app.value_map,'On')
                imshow(app.slice_sag(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.sagital,'Colormap',hot);
            elseif strcmp(app.value_hsv,'On')
                imshow(app.slice_sag(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.sagital,'Colormap',hsv);
            else
                imshow(app.slice_sag(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.sagital);
            end
        end

        % Value changed function: HotSwitch
        function HotSwitchValueChanged(app, event)
            app.value_map = app.HotSwitch.Value;
            app.HSVSwitch.Value = 'Off';
            app.value_hsv = 'Off';
            if strcmp(app.value_map,'On')
                imshow(app.slice_axi(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.axial,'Colormap',hot);
                imshow(app.slice_cor(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.coronal,'Colormap',hot);
                imshow(app.slice_sag(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.sagital,'Colormap',hot);
            else
                imshow(app.slice_axi(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.axial);
                imshow(app.slice_cor(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.coronal);
                imshow(app.slice_sag(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.sagital)
            end
        end

        % Value changed function: HSVSwitch
        function HSVSwitchValueChanged(app, event)
            app.value_hsv = app.HSVSwitch.Value;
            app.HotSwitch.Value = 'Off';
            app.value_map = 'Off';
            if strcmp(app.value_hsv,'On')
                imshow(app.slice_axi(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.axial,'Colormap',hsv);
                imshow(app.slice_cor(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.coronal,'Colormap',hsv);
                imshow(app.slice_sag(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.sagital,'Colormap',hsv);
            else
                imshow(app.slice_axi(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.axial);
                imshow(app.slice_cor(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.coronal);
                imshow(app.slice_sag(:,:,1),[app.min_intensity,app.max_intensity],'Parent',app.sagital)
            end
        end

        % Button pushed function: AnonimizardatosButton
        function AnonimizardatosButtonPushed(app, event)
            data_info = app.DCMinfo;
            path_image = app.path;
            img_names = app.names;
            anonimo(data_info,path_image,img_names)
            %for i=1:length(img_names)
            %    dicomanon(char(strcat(path_image,img_names(i))),char(strcat(path_image,"anom "+img_names(i))),"keep",["PatientAge","PatientSex","StudyDescription"])
            %    progress = round(i/length(img_names)*100);
            %    if i == 1
            %        app.Label.Text = num2str(progress)+ "%";
            %        pause(0.001)
            %    end
            %    if mod(progress,10) == 0
            %        app.Label.Text = num2str(progress)+ "%";
            %        pause(0.001)
            %    end
            %end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.tp5 = uifigure('Visible', 'off');
            app.tp5.Name = 'MATLAB App';
            app.axial = uiaxes(app.tp5);
            title(app.axial, 'Corte axial')
            app.axial.XTickLabel = '';
            app.axial.YTickLabel = '';
            app.coronal = uiaxes(app.tp5);
            title(app.coronal, 'Corte coronal')
            app.coronal.XTickLabel = '';
            app.coronal.YTickLabel = '';
            app.sagital = uiaxes(app.tp5);
            title(app.sagital, 'Corte sagital')
            app.sagital.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.sagital.XTickLabel = '';
            app.sagital.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.sagital.YTickLabel = '';
            app.CorteLabel_2 = uilabel(app.tp5);
            app.CorteLabel_2.Text = '';
            app.slide_axial = uislider(app.tp5);
            app.slide_axial.Limits = [1 100];
            app.slide_axial.ValueChangedFcn = createCallbackFcn(app, @slide_axialValueChanged, true);
            app.slide_axial.Tooltip = {'Desliza la pestaña'};
            app.slide_axial.Value = 1;
            app.NmerodecorteLabel = uilabel(app.tp5);
            app.NmerodecorteLabel.Text = 'Número de corte';
            app.corte_coronal = uieditfield(app.tp5, 'numeric');
            app.corte_coronal.ValueChangedFcn = createCallbackFcn(app, @corte_coronalValueChanged, true);
            app.CorteLabel = uilabel(app.tp5);
            app.CorteLabel.Text = '';
            app.slide_coronal = uislider(app.tp5);
            app.slide_coronal.Limits = [1 100];
            app.slide_coronal.ValueChangedFcn = createCallbackFcn(app, @slide_coronalValueChanged, true);
            app.slide_coronal.Value = 1;
            app.CortesLabel = uilabel(app.tp5);
            app.CortesLabel.Text = '';
            app.slide_sagital = uislider(app.tp5);
            app.slide_sagital.Limits = [1 100];
            app.slide_sagital.ValueChangedFcn = createCallbackFcn(app, @slide_sagitalValueChanged, true);
            app.slide_sagital.Value = 1;
            app.NmerodecorteLabel_2 = uilabel(app.tp5);
            app.NmerodecorteLabel_2.Text = 'Número de corte';
            app.corte_sagital = uieditfield(app.tp5, 'numeric');
            app.corte_sagital.ValueChangedFcn = createCallbackFcn(app, @corte_sagitalValueChanged, true);
            app.NmerodecorteLabel_3 = uilabel(app.tp5);
            app.NmerodecorteLabel_3.Text = 'Número de corte';
            app.corte_axial = uieditfield(app.tp5, 'numeric');
            app.corte_axial.ValueChangedFcn = createCallbackFcn(app, @corte_axialValueChanged, true);
            app.ValormnimoEditFieldLabel = uilabel(app.tp5);
            app.ValormnimoEditFieldLabel.Text = 'Valor mínimo';
            app.ValormnimoEditField = uieditfield(app.tp5, 'numeric');
            app.ValormnimoEditField.ValueChangedFcn = createCallbackFcn(app, @ValormnimoEditFieldValueChanged, true);
            app.ValormximoEditFieldLabel = uilabel(app.tp5);
            app.ValormximoEditFieldLabel.Text = 'Valor máximo';
            app.ValormximoEditField = uieditfield(app.tp5, 'numeric');
            app.ValormximoEditField.ValueChangedFcn = createCallbackFcn(app, @ValormximoEditFieldValueChanged, true);
            app.EspectroLabel = uilabel(app.tp5);
            app.EspectroLabel.Text = 'Espectro';
            app.ImportarimgenesButton = uibutton(app.tp5, 'push');
            app.ImportarimgenesButton.ButtonPushedFcn = createCallbackFcn(app, @ImportarimgenesButtonPushed, true);
            app.ImportarimgenesButton.Text = 'Importar imágenes';
            app.Image_2 = uiimage(app.tp5);
            app.Image_2.ImageSource = 'logo.jpeg';
            app.Image_3 = uiimage(app.tp5);
            app.Image_3.ImageSource = 'logo.jpeg';
            app.Image_4 = uiimage(app.tp5);
            app.Image_4.ImageSource = 'logo.jpeg';
            app.Image_1 = uiimage(app.tp5);
            app.Image_1.Tooltip = {'Cargando...'};
            app.Image_1.ImageSource = 'cargando.gif';
            app.Image_5 = uiimage(app.tp5);
            app.Image_5.Tooltip = {'Cargando...'};
            app.Image_5.ImageSource = 'cargando.gif';
            app.Image_6 = uiimage(app.tp5);
            app.Image_6.Tooltip = {'Cargando...'};
            app.Image_6.ImageSource = 'cargando.gif';
            app.HotSwitchLabel = uilabel(app.tp5);
            app.HotSwitchLabel.Text = 'Hot';
            app.HotSwitch = uiswitch(app.tp5, 'slider');
            app.HotSwitch.ValueChangedFcn = createCallbackFcn(app, @HotSwitchValueChanged, true);
            app.HSVSwitchLabel = uilabel(app.tp5);
            app.HSVSwitchLabel.Text = 'HSV';
            app.HSVSwitch = uiswitch(app.tp5, 'slider');
            app.HSVSwitch.ValueChangedFcn = createCallbackFcn(app, @HSVSwitchValueChanged, true);
            app.AnonimizardatosButton = uibutton(app.tp5, 'push');
            app.AnonimizardatosButton.ButtonPushedFcn = createCallbackFcn(app, @AnonimizardatosButtonPushed, true);
            app.AnonimizardatosButton.Text = 'Anonimizar datos';
            app.ImageMedicLabel = uilabel(app.tp5);
            app.ImageMedicLabel.Text = 'Image Medic';
            % Show the figure after all components are created
            app.tp5.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TP5_IAIM_CUCEI_2022A_EGAM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.tp5)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.tp5)
        end
    end
end