classdef anonimo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Image                 matlab.ui.control.Image
        path_destino          matlab.ui.control.Label
        text_path             matlab.ui.control.Label
        CarpetadestinoButton  matlab.ui.control.Button
        Label_3               matlab.ui.control.Label
        Label_2               matlab.ui.control.Label
        AnonimizarButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        path_anom;
        filename;% Description
        path_out;
        pathdir; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, DCMinfo, path, image_names)
            app.filename = image_names;
            app.path_anom = path;
            app.Label_3.Visible = 'off';
            app.CarpetadestinoButton.Visible = 'off';
        end

        % Button pushed function: AnonimizarButton
        function AnonimizarButtonPushed(app, event)
            %app.AnonimizarButton.Enable = 'off';
            app.pathdir = strcat(uigetdir,'\');
            app.CarpetadestinoButton.Visible = 'on';
            app.path_destino.Text = char(app.pathdir);
            for i=1:length(app.filename)
                dicomanon(char(strcat(app.path_anom,app.filename(i))),char(strcat(app.pathdir,"anom "+app.filename(i))),"keep",["PatientAge","PatientSex","StudyDescription"])
                progress = round(i/length(app.filename)*100);
                if i == 1
                    app.Label_2.Text = num2str(progress)+ "%";
                    pause(0.001)
                end
                if mod(progress,10) == 0
                    app.Label_2.Text = num2str(progress)+ "%";
                    pause(0.001)
                end
            end
            app.Label_3.Visible = "on";
            app.Label_3.Text = "La imagen DICOM ha sido anonimizada correctamente";
            pause(3)
            app.delete;
        end

        % Button pushed function: CarpetadestinoButton
        function CarpetadestinoButtonPushed(app, event)

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AnonimizarButton = uibutton(app.UIFigure, 'push');
            app.AnonimizarButton.ButtonPushedFcn = createCallbackFcn(app, @AnonimizarButtonPushed, true);
            app.AnonimizarButton.Text = 'Anonimizar';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.CarpetadestinoButton = uibutton(app.UIFigure, 'push');
            app.CarpetadestinoButton.ButtonPushedFcn = createCallbackFcn(app, @CarpetadestinoButtonPushed, true);
            app.CarpetadestinoButton.Text = 'Carpeta destino';
            app.text_path = uilabel(app.UIFigure);
            app.text_path.Text = {'Al presionar el botón, seleccionará la carpeta de destino donde se guardarán las imágenes.'; 'Automáticamente comenzará la anonimización'};
            app.path_destino = uilabel(app.UIFigure);
            app.path_destino.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo_back_zero.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = anonimo(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end